classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EncryptionTab                   matlab.ui.container.Tab
        WritetheencryptedonthediskButton  matlab.ui.control.StateButton
        Label_2                         matlab.ui.control.Label
        EncryptionTimemsLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        FilenameLabel_2                 matlab.ui.control.Label
        PathLabel                       matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.StateButton
        EncryptButton                   matlab.ui.control.StateButton
        EncryptionModelDropDown         matlab.ui.control.DropDown
        EncryptionModelDropDownLabel    matlab.ui.control.Label
        MessagesEditField               matlab.ui.control.EditField
        MessagesEditFieldLabel          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DecryptionTab                   matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        DecryptionTimemsLabel           matlab.ui.control.Label
        FilenameLabel_3                 matlab.ui.control.Label
        PathLabel_3                     matlab.ui.control.Label
        BrowseButton_3                  matlab.ui.control.StateButton
        DecryptButton                   matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        DecryptedMessagesLabel          matlab.ui.control.Label
        DecryptionModelDropDown         matlab.ui.control.DropDown
        DecryptionModelDropDownLabel    matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        PathLabel_2                     matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.StateButton
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        AboutTab                        matlab.ui.container.Tab
        DescriptionPanel                matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        PeoplePanel                     matlab.ui.container.Panel
        ThaweesakTrongtirakulDEngLabel_3  matlab.ui.control.Label
        ThaweesakTrongtirakulDEngLabel_2  matlab.ui.control.Label
        ThaweesakTrongtirakulDEngLabel  matlab.ui.control.Label
        Image5_4                        matlab.ui.control.Image
        Image5_3                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BrowseButton
        function BrowseButtonValueChanged(app, event)
            [filename,pathfile] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, 'Select File to Open');
            app.FilenameLabel_2.Text = [pathfile,filename];
            img = imread(app.FilenameLabel_2.Text);
            app.Image.ImageSource = img;
            app.EncryptButton.Enable = "on";
        end

        % Value changed function: EncryptButton
        function EncryptButtonValueChanged(app, event)
            img = imread(app.FilenameLabel_2.Text);
            condition = app.EncryptionModelDropDown.Value;
            txt = app.MessagesEditField.Value;
            txt = sprintf(txt);
            [x,y,z]     = size(img);
            message     = randi([1,45], x * y * z, 1)';
            message(1:size(txt,2)) = txt;
            message     = reshape(message, x, y, z);

            switch(condition)
                case 'XNOR' %Exclusive XNOR
                    tic
                    EN = 255-bitxor(uint8(message),uint8(img));
                    t = toc;
                case 'XOR' % Exclusibe XOR
                    tic
                    EN = bitxor(uint8(message),uint8(img));
                    t = toc;
                case 'AND' % AND
                    tic
                    EN = bitand(uint8(message),uint8(img));
                    t = toc;
                case 'NAND' % NAND
                     tic
                    EN = 255-bitand(uint8(message),uint8(img));
                     t = toc;
                case 'OR' % OR
                     tic
                    EN = bitor(uint8(message),uint8(img));
                     t = toc;
                otherwise % NOR
                    tic
                    EN = 255-bitor(uint8(message),uint8(img));
                    t = toc;
            end
            app.Image2.ImageSource = EN;
            app.Label_2.Text = num2str(t*1000);
            app.WritetheencryptedonthediskButton.Enable = "on";
        end

        % Value changed function: WritetheencryptedonthediskButton
        function WritetheencryptedonthediskButtonValueChanged(app, event)
            [file,path] = uiputfile('*.png','Save Image on the Disk','Roger.png');
            EN =       app.Image2.ImageSource;   
            imwrite(EN, [path,file],'png');
        end

        % Value changed function: BrowseButton_2
        function BrowseButton_2ValueChanged(app, event)
            [filename,pathfile] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, 'Select File to Open');
            app.FilenameLabel.Text = [pathfile,filename];
            img = imread(app.FilenameLabel.Text);
            app.Image3.ImageSource = img;         
            app.BrowseButton_3.Enable = "on";
        end

        % Value changed function: DecryptButton
        function DecryptButtonValueChanged(app, event)
            img = imread(app.FilenameLabel.Text);
            en = imread(app.FilenameLabel_3.Text);
            condition = app.DecryptionModelDropDown.Value;
            [x,y,z] = size(img);
            switch(condition)
                case 'XNOR' %Exclusive XNOR
                    tic
                    key = 255-bitxor(uint8(en),uint8(img));
                    t = toc;
                case 'XOR' % Exclusibe XOR
                    tic
                    key = bitxor(uint8(en),uint8(img));
                    t = toc;
                case 'AND' % AND
                    tic
                    key = bitand(uint8(en),uint8(img));
                    t = toc;
                case 'NAND' % NAND
                     tic
                    key = 255-bitand(uint8(en),uint8(img));
                     t = toc;
                case 'OR' % OR
                    tic
                    key = bitor(uint8(en),uint8(img));
                    t = toc;
                otherwise % NOR
                    tic
                    key = 255-bitor(uint8(en),uint8(img));
                    t = toc;
            end
            key = reshape(key, x * y * z, 1);
            app.EditField.Value = char(key(key>45))';
            app.Label_3.Text = num2str(t*1000);
        end

        % Value changed function: BrowseButton_3
        function BrowseButton_3ValueChanged(app, event)
            [filename,pathfile] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, 'Select File to Open');
            app.FilenameLabel_3.Text = [pathfile,filename];
            img = imread(app.FilenameLabel_3.Text);
            app.Image4.ImageSource = img;         
            app.DecryptButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EncryptionTab = uitab(app.TabGroup);
            app.EncryptionTab.Title = 'Encryption';
            app.Image = uiimage(app.EncryptionTab);
            app.Image.ImageSource = 'one.png';
            app.MessagesEditFieldLabel = uilabel(app.EncryptionTab);
            app.MessagesEditFieldLabel.Text = 'Messages';
            app.MessagesEditField = uieditfield(app.EncryptionTab, 'text');
            app.MessagesEditField.Value = 'Input Here';
            app.EncryptionModelDropDownLabel = uilabel(app.EncryptionTab);
            app.EncryptionModelDropDownLabel.Text = 'Encryption Model';
            app.EncryptionModelDropDown = uidropdown(app.EncryptionTab);
            app.EncryptionModelDropDown.Items = {'AND', 'NAND', 'OR', 'NOR', 'XOR', 'XNOR'};
            app.EncryptionModelDropDown.Value = 'AND';
            app.EncryptButton = uibutton(app.EncryptionTab, 'state');
            app.EncryptButton.ValueChangedFcn = createCallbackFcn(app, @EncryptButtonValueChanged, true);
            app.EncryptButton.Enable = 'off';
            app.EncryptButton.Text = 'Encrypt';
            app.BrowseButton = uibutton(app.EncryptionTab, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Text = 'Browse...';
            app.PathLabel = uilabel(app.EncryptionTab);
            app.PathLabel.Text = 'Path:';
            app.FilenameLabel_2 = uilabel(app.EncryptionTab);
            app.FilenameLabel_2.Text = 'Filename';
            app.Image2 = uiimage(app.EncryptionTab);
            app.Image2.ImageSource = 'two.png';
            app.EncryptionTimemsLabel = uilabel(app.EncryptionTab);
            app.EncryptionTimemsLabel.Text = 'Encryption Time (ms)';
            app.Label_2 = uilabel(app.EncryptionTab);
            app.Label_2.Text = '0';
            app.WritetheencryptedonthediskButton = uibutton(app.EncryptionTab, 'state');
            app.WritetheencryptedonthediskButton.ValueChangedFcn = createCallbackFcn(app, @WritetheencryptedonthediskButtonValueChanged, true);
            app.WritetheencryptedonthediskButton.Enable = 'off';
            app.WritetheencryptedonthediskButton.Text = 'Write the encrypted on the disk';
            app.DecryptionTab = uitab(app.TabGroup);
            app.DecryptionTab.Title = 'Decryption';
            app.Image3 = uiimage(app.DecryptionTab);
            app.Image3.ImageSource = 'one.png';
            app.Image4 = uiimage(app.DecryptionTab);
            app.Image4.ImageSource = 'two.png';
            app.BrowseButton_2 = uibutton(app.DecryptionTab, 'state');
            app.BrowseButton_2.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_2ValueChanged, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.PathLabel_2 = uilabel(app.DecryptionTab);
            app.PathLabel_2.Text = 'Path:';
            app.FilenameLabel = uilabel(app.DecryptionTab);
            app.FilenameLabel.Text = 'Filename';
            app.DecryptionModelDropDownLabel = uilabel(app.DecryptionTab);
            app.DecryptionModelDropDownLabel.Text = 'Decryption Model';
            app.DecryptionModelDropDown = uidropdown(app.DecryptionTab);
            app.DecryptionModelDropDown.Items = {'AND', 'NAND', 'OR', 'NOR', 'XOR', 'XNOR'};
            app.DecryptionModelDropDown.Value = 'AND';
            app.DecryptedMessagesLabel = uilabel(app.DecryptionTab);
            app.DecryptedMessagesLabel.Text = 'Decrypted Messages:';
            app.EditField = uieditfield(app.DecryptionTab, 'text');
            app.DecryptButton = uibutton(app.DecryptionTab, 'state');
            app.DecryptButton.ValueChangedFcn = createCallbackFcn(app, @DecryptButtonValueChanged, true);
            app.DecryptButton.Enable = 'off';
            app.DecryptButton.Text = 'Decrypt';
            app.BrowseButton_3 = uibutton(app.DecryptionTab, 'state');
            app.BrowseButton_3.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_3ValueChanged, true);
            app.BrowseButton_3.Enable = 'off';
            app.BrowseButton_3.Text = 'Browse...';
            app.PathLabel_3 = uilabel(app.DecryptionTab);
            app.PathLabel_3.Text = 'Path:';
            app.FilenameLabel_3 = uilabel(app.DecryptionTab);
            app.FilenameLabel_3.Text = 'Filename';
            app.DecryptionTimemsLabel = uilabel(app.DecryptionTab);
            app.DecryptionTimemsLabel.Text = 'Decryption Time (ms)';
            app.Label_3 = uilabel(app.DecryptionTab);
            app.Label_3.Text = '0';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.PeoplePanel = uipanel(app.AboutTab);
            app.PeoplePanel.Title = 'People';
            app.Image5 = uiimage(app.PeoplePanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Thaweesak.JPG');
            app.Image5_3 = uiimage(app.PeoplePanel);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, '6950.jpg');
            app.Image5_4 = uiimage(app.PeoplePanel);
            app.Image5_4.ImageSource = fullfile(pathToMLAPP, '6955.jpg');
            app.ThaweesakTrongtirakulDEngLabel = uilabel(app.PeoplePanel);
            app.ThaweesakTrongtirakulDEngLabel.Text = {'Thaweesak Trongtirakul, D.Eng.'; 'Electrical Engineering. Industrual Education.'; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'SPIE Member'; 'E-mail : thaweesak.tr@rmutp.ac.th'};
            app.ThaweesakTrongtirakulDEngLabel_2 = uilabel(app.PeoplePanel);
            app.ThaweesakTrongtirakulDEngLabel_2.Text = {'Adsadayut Aimsombat , Student'; 'Electrical Engineering. Industrual Education.'; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'E-mail : Adsadayut-a@rmutp.ac.th'; 'Tel.0863303807'};
            app.ThaweesakTrongtirakulDEngLabel_3 = uilabel(app.PeoplePanel);
            app.ThaweesakTrongtirakulDEngLabel_3.Text = {'Theerapon Sukjeen , Student'; 'Electrical Engineering. Industrual Education.'; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'E-mail : Theerapon-su@rmutp.ac.th'; 'Tel.0618214501'};
            app.DescriptionPanel = uipanel(app.AboutTab);
            app.DescriptionPanel.Title = 'Description';
            app.Label_4 = uilabel(app.DescriptionPanel);
            app.Label_4.Text = 'Data privacy and security are vital today. Safeguarding data is paramount, especially valuable data like images with text. Text-based image encryption enhances security by embedding text in images. Encryption techniques and keys secure the process. Encrypted text can be retrieved after decryption. This approach combines cryptography and steganography, protecting sensitive information in visual content. Its goal is to add security to data, particularly text-based information in visual media.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end